classdef Final_work < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        DynamicButton      matlab.ui.control.Button
        FogButton          matlab.ui.control.Button
        Under_waterButton  matlab.ui.control.Button
        Image              matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Image clicked function: Image
        function ImageClicked(app, event)
            [file, path] = uigetfile({'*.jpg;*.png'}, 'Select an image file');
            % 检查用户是否取消操作
            if isequal(file, 0)
                return; % 用户取消操作
            end
            % 获取图像的完整路径
            imagePath = fullfile(path, file); % 图片的完整路径
            % 更新 Image 组件的 ImageSource 属性
            app.Image.ImageSource = imagePath; % 设置图片源路径
        end

        % Button pushed function: Under_waterButton
        function Under_water(app, event)
            % 获取当前显示的图像数据
            current_path = app.Image.ImageSource; 
            % 如果图像数据为空，可以选择提示用户或者直接返回
            if isempty(current_path)
                disp('没有选择图像！');
                return;
            end
            % 调用水下图像复原函数
            restored_image = water_test(current_path);
            % 显示结果（可选）
            imshow(restored_image);
        end

        % Button pushed function: FogButton
        function Fog(app, event)
            %获取当前显示的图像数据
            current_path = app.Image.ImageSource;
            % 如果图像数据为空，可以选择提示用户或者直接返回
            if isempty(current_path)
                disp('没有选择图像！');
                return;
            end
            % 调用雾天图像复原函数
            restored_image = fog_work(current_path);
            % 显示结果（可选）
            imshow(restored_image);
        end

        % Button pushed function: DynamicButton
        function Dynamic(app, event)
            %获取当前显示的图像数据
            current_path = app.Image.ImageSource;
            % 如果图像数据为空，可以选择提示用户或者直接返回
            if isempty(current_path)
                disp('没有选择图像！');
                return;
            end
            % 调用运动模糊图像复原函数
            restored_image = dynamic_test(current_path);
            % 显示结果（可选）
            imshow(restored_image);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Under_waterButton = uibutton(app.UIFigure, 'push');
            app.Under_waterButton.ButtonPushedFcn = createCallbackFcn(app, @Under_water, true);
            app.Under_waterButton.Text = 'Under_water';
            app.FogButton = uibutton(app.UIFigure, 'push');
            app.FogButton.ButtonPushedFcn = createCallbackFcn(app, @Fog, true);
            app.FogButton.Text = 'Fog';
            app.DynamicButton = uibutton(app.UIFigure, 'push');
            app.DynamicButton.ButtonPushedFcn = createCallbackFcn(app, @Dynamic, true);
            app.DynamicButton.Text = 'Dynamic';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Final_work

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end